classdef Musclemodeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleModel             matlab.ui.Figure
        PlotButton              matlab.ui.control.Button
        FromLabel               matlab.ui.control.Label
        ToLabel                 matlab.ui.control.Label
        TimeTo                  matlab.ui.control.NumericEditField
        TimeSegment             matlab.ui.control.NumericEditField
        IntervalsLabel          matlab.ui.control.Label
        TimeLabel               matlab.ui.control.Label
        TimeFrom                matlab.ui.control.NumericEditField
        KseLabel                matlab.ui.control.Label
        KpeLabel                matlab.ui.control.Label
        TceLabel                matlab.ui.control.Label
        BLabel                  matlab.ui.control.Label
        eLabel                  matlab.ui.control.Label
        KseValue                matlab.ui.control.NumericEditField
        KpeValue                matlab.ui.control.NumericEditField
        TceValue                matlab.ui.control.NumericEditField
        eValue                  matlab.ui.control.NumericEditField
        BetaValue               matlab.ui.control.NumericEditField
        stress_relaxation       matlab.ui.control.UIAxes
        fig_iso                 matlab.ui.control.UIAxes
        fig_creep               matlab.ui.control.UIAxes
        CreateTimeVectorButton  matlab.ui.control.Button
        Tenssion                matlab.ui.control.Label
        Tenssionvalue           matlab.ui.control.NumericEditField
        fig_hills_model         matlab.ui.control.UIAxes
        StressRelaxationButton  matlab.ui.control.Button
        IsometricButton         matlab.ui.control.Button
        Lamp_iso                matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        HillsModelButton        matlab.ui.control.Button
        CreepButton             matlab.ui.control.Button
        Lamp_hillsmodel         matlab.ui.control.Lamp
        Lamp_creep              matlab.ui.control.Lamp
    end

    properties (Access = private)
        model_stress_relaxation; 
        lowerlimit_stress_relaxation;
        upperlimit_stress_relaxation;
        model_iso;
        upperlimit_iso;
        t;
        upper_limit_creep;
        model_creep;
        model_s;
        model_t;
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot (app.stress_relaxation ,app.model_stress_relaxation);
            app.Lamp.Enable = 'off' ;
            
            
            % plot the Isometric
           %tx = 0 : length (app.t) ;
           %x = ones(1,length (app.t)+1) .* app.upperlimit_iso;
            plot (app.fig_iso ,app.model_iso);
           % hold on;
           % plot(app.fig_iso, tx ,x);
            app.Lamp_iso.Enable = 'off'; 
            %% Plot creep
            plot (app.fig_creep, app.model_creep)
            app.Lamp_creep.Enable = 'off'; 
            %% plot_hills_model
            plot(app.fig_hills_model , app.model_s,app.model_t);
            app.Lamp_hillsmodel.Enable = 'off';

        end

        % Value changed function: TimeFrom
        function TimeFromValueChanged(app, event)
           
        end

        % Button pushed function: StressRelaxationButton
        function StressRelaxationButtonPushed(app, event)
            [app.upperlimit_stress_relaxation,app.model_stress_relaxation,app.lowerlimit_stress_relaxation]= ...
                hill_stress_relax (app.KseValue.Value,app.KpeValue.Value...
                ,app.TceValue.Value,app.BetaValue.Value,app.eValue.Value,app.t);
           app.Lamp.Enable =  'on' ; 
         %   f = msgbox('Operation Complete', 'Model Status','warn');
        end

        % Button pushed function: IsometricButton
        function IsometricButtonPushed(app, event)
          [app.upperlimit_iso,app.model_iso] = hill_isometric (app.KseValue.Value,app.KpeValue.Value...
                ,app.TceValue.Value,app.BetaValue.Value,app.eValue.Value,app.t);
          app.Lamp_iso.Enable =  'on' ; 

        end

        % Value changed function: TimeSegment
        function TimeSegmentValueChanged(app, event)
            value = app.TimeSegment.Value;            
        end

        % Button pushed function: CreepButton
        function CreepButtonPushed(app, event)
          [app.upper_limit_creep,app.model_creep] = hill_creep(app.KseValue.Value,app.KpeValue.Value,...
          app.BetaValue.Value,app.t);
          app.Lamp_creep.Enable = 'on';
        end

        % Button pushed function: CreateTimeVectorButton
        function CreateTimeVectorButtonPushed(app, event)
          t1 = app.TimeFrom.Value;
          t2 = app.TimeTo.Value;
          app.t = t1:app.TimeSegment.Value:t2;
          
          app.IsometricButton.Enable = 'on'; 
          app.CreepButton.Enable = 'on';
          app.StressRelaxationButton.Enable= 'on';
          app.HillsModelButton.Enable = 'on';
          
          %app.CreateTimeVectorButton.Enable ='off';
        end

        % Button pushed function: HillsModelButton
        function HillsModelButtonPushed(app, event)
            [app.model_s,app.model_t] = hill_model (app.KseValue.Value, app.KpeValue.Value, app.TceValue.Value, app.BetaValue.Value...
                 ,app.Tenssionvalue.Value , app.eValue.Value, app.t);
            app.Lamp_hillsmodel.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleModel = uifigure;
            app.MuscleModel.Name = 'UI Figure';
            app.PlotButton = uibutton(app.MuscleModel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FromLabel = uilabel(app.MuscleModel);
            app.FromLabel.Text = 'From';
            app.ToLabel = uilabel(app.MuscleModel);
            app.ToLabel.Text = 'To';
            app.TimeTo = uieditfield(app.MuscleModel, 'numeric');
            app.TimeTo.Limits = [0 Inf];
            app.TimeTo.Value = 10;
            app.TimeSegment = uieditfield(app.MuscleModel, 'numeric');
            app.TimeSegment.Limits = [0 1];
            app.TimeSegment.ValueDisplayFormat = '%.2f';
            app.TimeSegment.ValueChangedFcn = createCallbackFcn(app, @TimeSegmentValueChanged, true);
            app.TimeSegment.Value = 0.01;
            app.IntervalsLabel = uilabel(app.MuscleModel);
            app.IntervalsLabel.Text = 'Intervals';
            app.TimeLabel = uilabel(app.MuscleModel);
            app.TimeLabel.Text = 'Time:';
            app.TimeFrom = uieditfield(app.MuscleModel, 'numeric');
            app.TimeFrom.Limits = [0 Inf];
            app.TimeFrom.ValueChangedFcn = createCallbackFcn(app, @TimeFromValueChanged, true);
            app.KseLabel = uilabel(app.MuscleModel);
            app.KseLabel.Text = 'Kse : ';
            app.KpeLabel = uilabel(app.MuscleModel);
            app.KpeLabel.Text = 'Kpe : ';
            app.TceLabel = uilabel(app.MuscleModel);
            app.TceLabel.Text = 'Tce : ';
            app.BLabel = uilabel(app.MuscleModel);
            app.BLabel.Text = 'B : ';
            app.eLabel = uilabel(app.MuscleModel);
            app.eLabel.Text = 'e : ';
            app.KseValue = uieditfield(app.MuscleModel, 'numeric');
            app.KseValue.Value = 380;
            app.KpeValue = uieditfield(app.MuscleModel, 'numeric');
            app.KpeValue.Value = 103;
            app.TceValue = uieditfield(app.MuscleModel, 'numeric');
            app.TceValue.Value = 1;
            app.eValue = uieditfield(app.MuscleModel, 'numeric');
            app.eValue.Value = 1;
            app.BetaValue = uieditfield(app.MuscleModel, 'numeric');
            app.BetaValue.Value = 5;
            app.stress_relaxation = uiaxes(app.MuscleModel);
            title(app.stress_relaxation, 'Stress Relaxation ')
            xlabel(app.stress_relaxation, 'Time ')
            ylabel(app.stress_relaxation, 'T(t)')
            app.fig_iso = uiaxes(app.MuscleModel);
            title(app.fig_iso, 'Isometric Contraction')
            xlabel(app.fig_iso, 'Time')
            ylabel(app.fig_iso, 'T(t)')
            app.fig_creep = uiaxes(app.MuscleModel);
            title(app.fig_creep, 'Creep')
            xlabel(app.fig_creep, 'Time')
            ylabel(app.fig_creep, 'Strain')
            app.CreateTimeVectorButton = uibutton(app.MuscleModel, 'push');
            app.CreateTimeVectorButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTimeVectorButtonPushed, true);
            app.CreateTimeVectorButton.Text = 'Create Time Vector';
            app.Tenssion = uilabel(app.MuscleModel);
            app.Tenssion.Text = 'T : ';
            app.Tenssionvalue = uieditfield(app.MuscleModel, 'numeric');
            app.Tenssionvalue.Value = 1;
            app.fig_hills_model = uiaxes(app.MuscleModel);
            title(app.fig_hills_model, 'Hill''s Model')
            xlabel(app.fig_hills_model, 'Strain')
            ylabel(app.fig_hills_model, 'Stress')
            app.StressRelaxationButton = uibutton(app.MuscleModel, 'push');
            app.StressRelaxationButton.ButtonPushedFcn = createCallbackFcn(app, @StressRelaxationButtonPushed, true);
            app.StressRelaxationButton.Enable = 'off';
            app.StressRelaxationButton.Text = 'Stress Relaxation';
            app.IsometricButton = uibutton(app.MuscleModel, 'push');
            app.IsometricButton.ButtonPushedFcn = createCallbackFcn(app, @IsometricButtonPushed, true);
            app.IsometricButton.Enable = 'off';
            app.IsometricButton.Text = 'Isometric';
            app.Lamp_iso = uilamp(app.MuscleModel);
            app.Lamp_iso.Enable = 'off';
            app.Lamp = uilamp(app.MuscleModel);
            app.Lamp.Enable = 'off';
            app.HillsModelButton = uibutton(app.MuscleModel, 'push');
            app.HillsModelButton.ButtonPushedFcn = createCallbackFcn(app, @HillsModelButtonPushed, true);
            app.HillsModelButton.Enable = 'off';
            app.HillsModelButton.Text = 'Hill''s Model';
            app.CreepButton = uibutton(app.MuscleModel, 'push');
            app.CreepButton.ButtonPushedFcn = createCallbackFcn(app, @CreepButtonPushed, true);
            app.CreepButton.Enable = 'off';
            app.CreepButton.Text = 'Creep';
            app.Lamp_hillsmodel = uilamp(app.MuscleModel);
            app.Lamp_hillsmodel.Enable = 'off';
            app.Lamp_creep = uilamp(app.MuscleModel);
            app.Lamp_creep.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Musclemodeling

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleModel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleModel)
        end
    end
end